classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadImageButton                 matlab.ui.control.Button
        StdforgaussianalgorithmEditField  matlab.ui.control.EditField
        StdforgaussianalgorithmEditFieldLabel  matlab.ui.control.Label
        EdgeDetectionAlgorithmDropDown  matlab.ui.control.DropDown
        EdgeDetectionAlgorithmDropDownLabel  matlab.ui.control.Label
        GreyscaleImageCanvas            matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        EdgesCanvas                     matlab.ui.control.UIAxes
        BlurImageCanvas                 matlab.ui.control.UIAxes
        OriginalImageCanvas             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Image = []; % greyscale image (initially empty);
        GreyscaleImage = []; % greyscale image (initially empty)
        EdgeDetectionAlgorithm = 'Laplace';
        GaussianBlurStd = 0.5;
    end
    
    methods (Access = private)
        
        function processImage(app)
            % Only run when an image is already loaded
            if not(isempty(app.Image))
                % Convert image to grayscale and display
                app.GreyscaleImage = convertToGreyScale(app, app.Image);
                imshow(app.GreyscaleImage, 'parent', app.GreyscaleImageCanvas);
                
                % Calculate and display blurred image
                blurImage = imgaussfilt(app.GreyscaleImage, app.GaussianBlurStd);
                imshow(blurImage, 'parent', app.BlurImageCanvas);
                
                % Find edges and display
                edges = edgeDetection(app, blurImage, app.EdgeDetectionAlgorithm);
                imshow(edges, 'parent', app.EdgesCanvas);
            end
        end
        
        function onChangeImage(app, imagePath)
            % Load and display original image
            app.Image = imread(imagePath);
            imshow(app.Image, 'parent', app.OriginalImageCanvas);
            
            % Process image
            processImage(app);
        end
        
        function onChangeEdgeDetectionAlgorithm(app, algorithm)
            app.EdgeDetectionAlgorithm = algorithm;
            processImage(app);
        end
        
        function onChangeGaussStd(app, std)
            app.GaussianBlurStd = std;
            processImage(app);
        end
        
        function greyscaleImage = convertToGreyScale(~, originalImage)
            [~, ~, channels] = size(originalImage);
            if channels > 1
                greyscaleImage = rgb2gray(originalImage);
            else
                greyscaleImage = originalImage;
            end
        end
        
        function edges = edgeDetection(~, image, algorithm)            
            switch(algorithm)
                case 'Laplace'
                    filter = [0 1 0; 1 -4 1; 0 1 0];
                    edges = uint8(conv2(double(image), double(filter), 'same'));
                case 'LoG'
                    filter = [0 0 -1 0 0; 0 -1 -2 -1 0; -1 -2 16 -2 -1; 0 -1 -2 -1 0; 0 0 -1 0 0];
                    edges = uint8(conv2(double(image), double(filter), 'same'));
                case 'Sobel'
                    filterX = [-1 0 1; -2 0 2; -1 0 1];
                    filterY = [1 2 1; 0 0 0; -1 -2 -1];
                    edgesX = conv2(double(image), double(filterX), 'same');
                    edgesY = conv2(double(image), double(filterY), 'same');
                    edges = uint8(sqrt(edgesX.^2 + edgesY.^2));
                case 'Prewitt'
                    filterX = [-1 0 1; -1 0 1; -1 0 1];
                    filterY = [1 1 1; 0 0 0; -1 -1 -1];
                    edgesX = conv2(double(image), double(filterX), 'same');
                    edgesY = conv2(double(image), double(filterY), 'same');
                    edges = uint8(sqrt(edgesX.^2 + edgesY.^2));
                case 'Roberts'
                    filterX = [1 0; 0 -1];
                    filterY = [0 1; -1 0]';
                    edgesX = conv2(double(image), double(filterX), 'same');
                    edgesY = conv2(double(image), double(filterY), 'same');
                    edges = uint8(sqrt(edgesX.^2 + edgesY.^2));
                case 'Canny'
                    edges = edge(image, 'canny');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Process default image
            app.onChangeImage('images\banana.jpeg');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               app.onChangeImage(fname);
            end
        end

        % Value changed function: StdforgaussianalgorithmEditField
        function StdforgaussianalgorithmEditFieldValueChanged(app, event)
            value = app.StdforgaussianalgorithmEditField.Value;
            number = str2double(value);
            
            % Call onChange function if only the input is numeric
            if isnumeric(number)
                app.onChangeGaussStd(number);
            end
        end

        % Value changed function: EdgeDetectionAlgorithmDropDown
        function EdgeDetectionAlgorithmDropDownValueChanged(app, event)
            value = app.EdgeDetectionAlgorithmDropDown.Value;
            app.onChangeEdgeDetectionAlgorithm(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImageCanvas = uiaxes(app.UIFigure);
            title(app.OriginalImageCanvas, 'Original Image')
            app.OriginalImageCanvas.XTick = [];
            app.OriginalImageCanvas.YTick = [];
            app.BlurImageCanvas = uiaxes(app.UIFigure);
            title(app.BlurImageCanvas, 'Gaussian Blur')
            app.BlurImageCanvas.XTick = [];
            app.BlurImageCanvas.YTick = [];
            app.EdgesCanvas = uiaxes(app.UIFigure);
            title(app.EdgesCanvas, 'Edge Detection')
            app.EdgesCanvas.XTick = [];
            app.EdgesCanvas.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Object Detection')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.GreyscaleImageCanvas = uiaxes(app.UIFigure);
            title(app.GreyscaleImageCanvas, 'Greyscale Image')
            app.GreyscaleImageCanvas.XTick = [];
            app.GreyscaleImageCanvas.YTick = [];
            app.EdgeDetectionAlgorithmDropDownLabel = uilabel(app.UIFigure);
            app.EdgeDetectionAlgorithmDropDownLabel.Text = 'Edge Detection Algorithm';
            app.EdgeDetectionAlgorithmDropDown = uidropdown(app.UIFigure);
            app.EdgeDetectionAlgorithmDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.EdgeDetectionAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionAlgorithmDropDownValueChanged, true);
            app.EdgeDetectionAlgorithmDropDown.Value = 'Laplace';
            app.StdforgaussianalgorithmEditFieldLabel = uilabel(app.UIFigure);
            app.StdforgaussianalgorithmEditFieldLabel.Text = 'Std for gaussian algorithm';
            app.StdforgaussianalgorithmEditField = uieditfield(app.UIFigure, 'text');
            app.StdforgaussianalgorithmEditField.ValueChangedFcn = createCallbackFcn(app, @StdforgaussianalgorithmEditFieldValueChanged, true);
            app.StdforgaussianalgorithmEditField.Value = '0.5';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end